classdef fNIRSInputArgsApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CurrentConfigPanel            matlab.ui.container.Panel
        CFGBrowseButton               matlab.ui.control.Button
        ConfigFileEditFieldLabel      matlab.ui.control.Label
        ConfigFileEditField           matlab.ui.control.EditField
        DeviceIDEditFieldLabel        matlab.ui.control.Label
        DeviceIDEditField             matlab.ui.control.EditField
        COMPortEditFieldLabel         matlab.ui.control.Label
        COMPortEditField              matlab.ui.control.EditField
        LoadButton                    matlab.ui.control.Button
        UpdateSettingsPanel           matlab.ui.container.Panel
        NewDeviceIDDropDownLabel      matlab.ui.control.Label
        NewDeviceIDDropDown           matlab.ui.control.DropDown
        NewCOMPortDropDownLabel       matlab.ui.control.Label
        NewCOMPortDropDown            matlab.ui.control.DropDown
        UpdateandSaveArgumentsButton  matlab.ui.control.Button
        SaveDirEditFieldLabel         matlab.ui.control.Label
        SaveDirEditField              matlab.ui.control.EditField
        SaveDirBrowseButton           matlab.ui.control.Button
        SaveNameEditFieldLabel        matlab.ui.control.Label
        SaveNameEditField             matlab.ui.control.EditField
        ContinueButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Main fNIRSapp
        DefaultCFG = 'fNIRS.cfg' % Default CFG file to read
        DevInfo % Contents of the config file
        Environment % Description
        FilePath % saves the filepath of the CFG file
        FileName % saves the name of the CFG file
        Path % CFG Path
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)
        function UpdateFields(app,devinfo)
            app.DeviceIDEditField.Value = devinfo.devID;
            app.COMPortEditField.Value = devinfo.commPort;
        end
        
        function [DevInfo,error,filepath] = CFGChecker(app,CFGFile)
            msgbox('Loading CFG')
            Escape = 0;
            while eq(Escape, 0)
                try
                    filepath = CFGFile;
                    [DevInfo,error] = CFGLoader(app,filepath);
                    if ~eq(error, 0)
                        Escape = menu('File loading error. Would you like to exit?', 'Yes', 'No'); % loop break option
                    end
                catch 
                    [filename,pathname] = uigetfile({'*.cfg', 'Hardware configuration file (*.cfg)'});
                    filePath = [pathname,filename];
                    
                    [DevInfo,error] = CFGLoader(app,filepath);
                    app.ConfigFileEditField.Value = filename;
                    if ~app.ConfigFileEditField.Value
                        Escape = menu('No config file loaded. Would you like to exit?', 'Yes', 'No'); % loop break option
                    elseif eq(strcmp(filename(end-3:end),'.cfg'), 0)
                        Escape = menu('Incorrect file type loaded. Would you like to exit?', 'Yes', 'No'); % loop break option
                    elseif ~exist(filePath, 'file')
                        Escape = menu('File not found. Would you like to exit?', 'Yes', 'No'); % loop break option
                    end
                end
                if exist("DevInfo","var") && eq(error, 0)
                    Escape = 2;
                end
            end
            if eq(Escape, 1)
                fNIRS_exit(app.CallingApp);
                closereq
            elseif eq(Escape, 2)
                return
            end
        end
        
        function [devinfo,error] = CFGLoader(app,file)
            if exist(file,'file')
                app.ConfigFileEditField.Value = file;
                if strcmp(file(end-3:end),'.cfg')
                    [devinfo,error] = loadCFG(file);
                end
                UpdateFields(app,devinfo)
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,'center')
            app.UpdateSettingsPanel.Visible = 'off';
            app.UIFigure.Visible = 'on';
            
            % Store main app object
            app.CallingApp = mainapp;
            
            
            % Collect startup arguments
%             try
%                 [app.DevInfo,error,app.FilePath] = CFGChecker(app,app.DefaultCFG);
%             catch ME
%                 msgbox(ME.identifier)
%                 return
%             end
                
            HWfile = fopen('hwcompatlist.txt');
            txt = textscan(HWfile,'%s');
            fclose(HWfile);
            StringData = string(txt{:});
            try
                app.NewDeviceIDDropDown.Items = transpose(StringData);
            catch ME
                msgbox(ME.identifier)
                return
            end  
            
            app.NewCOMPortDropDown.Items = serialportlist;
            
%             if error == 0
%                 app.UpdateSettingsPanel.Visible = 'on';
%             end
        end

        % Button pushed function: CFGBrowseButton
        function CFGBrowseButtonPushed(app, event)
            [filename,pathname] = uigetfile({'*.cfg', 'Hardware configuration file (*.cfg)'});
            app.FilePath = [pathname,filename];
            
            LoadButtonPushed(app)
        end

        % Button pushed function: UpdateandSaveArgumentsButton
        function UpdateandSaveArgumentsButtonPushed(app, event)
            app.DevInfo.devID = app.NewDeviceIDDropDown.Value;
            app.DevInfo.commPort = app.NewCOMPortDropDown.Value;
            
            UpdateFields(app,app.DevInfo);
            
            devinfo = app.DevInfo;
            savename = fullfile(app.Path,app.SaveNameEditField.Value);
            try
                save(savename,'devinfo')
            catch
                msgbox('Save failed')
            end
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            disp(['Using', app.FilePath])
            ninjaGUI(app.CallingApp, app.FilePath);
            
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            Escape = menu('File not found. Would you like to exit?', 'Yes', 'No'); % loop break option
            if eq(Escape, 1)
                delete(app)
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [app.DevInfo,~,app.FilePath] = CFGChecker(app,app.FilePath);
            if ~app.UpdateSettingsPanel.Visible
                app.UpdateSettingsPanel.Visible = 'on';
            end
        end

        % Button pushed function: SaveDirBrowseButton
        function SaveDirBrowseButtonPushed(app, event)
            app.Path = uigetdir(pwd,'Select path to save .cfg');
            app.SaveDirEditField.Value = app.Path;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CurrentConfigPanel = uipanel(app.UIFigure);
            app.CurrentConfigPanel.Title = 'Current Config';
            app.CFGBrowseButton = uibutton(app.CurrentConfigPanel, 'push');
            app.CFGBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @CFGBrowseButtonPushed, true);
            app.CFGBrowseButton.Text = '...';
            app.ConfigFileEditFieldLabel = uilabel(app.CurrentConfigPanel);
            app.ConfigFileEditFieldLabel.Text = 'Config File';
            app.ConfigFileEditField = uieditfield(app.CurrentConfigPanel, 'text');
            app.DeviceIDEditFieldLabel = uilabel(app.CurrentConfigPanel);
            app.DeviceIDEditFieldLabel.Text = 'Device ID';
            app.DeviceIDEditField = uieditfield(app.CurrentConfigPanel, 'text');
            app.DeviceIDEditField.Editable = 'off';
            app.COMPortEditFieldLabel = uilabel(app.CurrentConfigPanel);
            app.COMPortEditFieldLabel.Text = 'COM Port';
            app.COMPortEditField = uieditfield(app.CurrentConfigPanel, 'text');
            app.COMPortEditField.Editable = 'off';
            app.LoadButton = uibutton(app.CurrentConfigPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.UpdateSettingsPanel = uipanel(app.UIFigure);
            app.UpdateSettingsPanel.Title = 'Update Settings';
            app.NewDeviceIDDropDownLabel = uilabel(app.UpdateSettingsPanel);
            app.NewDeviceIDDropDownLabel.Text = 'New Device ID';
            app.NewDeviceIDDropDown = uidropdown(app.UpdateSettingsPanel);
            app.NewDeviceIDDropDown.Items = {};
            app.NewDeviceIDDropDown.Value = {};
            app.NewCOMPortDropDownLabel = uilabel(app.UpdateSettingsPanel);
            app.NewCOMPortDropDownLabel.Text = 'New COM Port';
            app.NewCOMPortDropDown = uidropdown(app.UpdateSettingsPanel);
            app.NewCOMPortDropDown.Items = {'', '', '', ''};
            app.NewCOMPortDropDown.Value = '';
            app.UpdateandSaveArgumentsButton = uibutton(app.UpdateSettingsPanel, 'push');
            app.UpdateandSaveArgumentsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateandSaveArgumentsButtonPushed, true);
            app.UpdateandSaveArgumentsButton.Text = {'Update and Save'; 'Arguments'};
            app.SaveDirEditFieldLabel = uilabel(app.UpdateSettingsPanel);
            app.SaveDirEditFieldLabel.Text = 'Save Dir';
            app.SaveDirEditField = uieditfield(app.UpdateSettingsPanel, 'text');
            app.SaveDirBrowseButton = uibutton(app.UpdateSettingsPanel, 'push');
            app.SaveDirBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDirBrowseButtonPushed, true);
            app.SaveDirBrowseButton.Text = '...';
            app.SaveNameEditFieldLabel = uilabel(app.UpdateSettingsPanel);
            app.SaveNameEditFieldLabel.Text = 'Save Name';
            app.SaveNameEditField = uieditfield(app.UpdateSettingsPanel, 'text');
            app.SaveNameEditField.Value = 'fNIRS.cfg';
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fNIRSInputArgsApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end