classdef hardwareselect < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SelecthardwareUIFigure   matlab.ui.Figure
        DevicetypeLabel          matlab.ui.control.Label
        DeviceSelection          matlab.ui.control.DropDown
        SerialportDropDownLabel  matlab.ui.control.Label
        PortSelection            matlab.ui.control.DropDown
        DoneButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        %maindlg % Need to store the main dialog here for things to work, due to current limitations of app builder
    end
    
    properties (Access = public)
        result=0; % Stores the result of the dialog
        port % string storing the selected serial port
        dev % string storing the selected hardware
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                        
            app.SelecthardwareUIFigure.Visible='off';
            movegui(app.SelecthardwareUIFigure,"center")
            app.SelecthardwareUIFigure.Visible='on';                        
            try
                app.DeviceSelection.Items=importdata('hwcompatlist.txt');
                app.dev = app.DeviceSelection.Value;
            catch ME
                msgbox(ME.identifier)
                app.result=0;
                app.endFcn(app)
                return
            end            
            app.PortSelection.Items=seriallist;
            app.port = app.PortSelection.Value;
        end

        % Value changed function: PortSelection
        function PortSelectionValueChanged(app, event)
            app.PortSelection.Items=seriallist;
            app.port = app.PortSelection.Value;
        end

        % Close request function: SelecthardwareUIFigure
        function endFcn(app, event)
            resultado=app.result;
            delete(app)
            if ~resultado
                %error and close program
                msgbox('Configuration was not successful')
            else
                %call the  fNIRS program again
                fNIRS();
            end
            
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            %validate fields, if things are wrong, set result as 0
            if isempty(app.port)
                %error message
                msgbox('No serial port selected')
                app.result=0;
            else
                
                %create object with all the parameters
                
                app.dev = app.DeviceSelection.Value;
                app.port = app.PortSelection.Value;
                
                %write cfg file
                
                %if everything went ok
                app.result=1;
            end
            %end the function anyway
            app.endFcn(app);
            return
        end

        % Value changed function: DeviceSelection
        function DeviceSelectionValueChanged(app, event)
            app.dev = app.DeviceSelection.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SelecthardwareUIFigure = uifigure('Visible', 'off');
            app.SelecthardwareUIFigure.Name = 'Select hardware';
            app.SelecthardwareUIFigure.CloseRequestFcn = createCallbackFcn(app, @endFcn, true);
            app.DevicetypeLabel = uilabel(app.SelecthardwareUIFigure);
            app.DevicetypeLabel.Text = 'Device type';
            app.DeviceSelection = uidropdown(app.SelecthardwareUIFigure);
            app.DeviceSelection.Items = {};
            app.DeviceSelection.ValueChangedFcn = createCallbackFcn(app, @DeviceSelectionValueChanged, true);
            app.DeviceSelection.Value = {};
            app.SerialportDropDownLabel = uilabel(app.SelecthardwareUIFigure);
            app.SerialportDropDownLabel.Text = 'Serial port';
            app.PortSelection = uidropdown(app.SelecthardwareUIFigure);
            app.PortSelection.Items = {};
            app.PortSelection.ValueChangedFcn = createCallbackFcn(app, @PortSelectionValueChanged, true);
            app.PortSelection.Value = {};
            app.DoneButton = uibutton(app.SelecthardwareUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.SelecthardwareUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hardwareselect

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SelecthardwareUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SelecthardwareUIFigure)
        end
    end
end